classdef RoboticsPackage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        WelcomeTab                   matlab.ui.container.Tab
        STARTButton                  matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        ArmDefinitionTab             matlab.ui.container.Tab
        AddJointstoArmLabel          matlab.ui.control.Label
        JointTypeButtonGroup         matlab.ui.container.ButtonGroup
        RevoluteButton               matlab.ui.control.RadioButton
        PrismaticButton              matlab.ui.control.RadioButton
        EnterDHParametersPanel       matlab.ui.container.Panel
        ORLabel                      matlab.ui.control.Label
        FindDHParametersForMeButton  matlab.ui.control.Button
        aEditFieldLabel              matlab.ui.control.Label
        aEditField                   matlab.ui.control.NumericEditField
        alphaEditFieldLabel          matlab.ui.control.Label
        alphaEditField               matlab.ui.control.NumericEditField
        dEditFieldLabel              matlab.ui.control.Label
        dEditField                   matlab.ui.control.NumericEditField
        thetaEditFieldLabel          matlab.ui.control.Label
        thetaEditField               matlab.ui.control.NumericEditField
        CurrentArmLabel              matlab.ui.control.Label
        AddAboveJointtoArmButton     matlab.ui.control.Button
        FinishedButton               matlab.ui.control.Button
        ClearArmandStartOverButton   matlab.ui.control.Button
        LinkLengthmEditFieldLabel    matlab.ui.control.Label
        LinkLengthmEditField         matlab.ui.control.NumericEditField
        CurrentArmTable              matlab.ui.control.Table
        SimulationTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        jointsTable = {};
        arm_dh = [];
        jointi = 1; % Used to keep track of which joint is being edited in arm creation
        armTable;
        Links = [];
        jointTypes = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: AddAboveJointtoArmButton
        function AddAboveJointtoArmButtonPushed(app, event)
            % Get values
            LinkLength = app.LinkLengthmEditField.Value; % Get link length
            dhi = [app.aEditField.Value,app.alphaEditField.Value,app.dEditField.Value,app.thetaEditField.Value]; % Get DH parameters
            
            % Update app values
            app.Links = [app.Links;LinkLength]; % Update link lengths
            app.jointsTable(app.jointi) = {num2str(app.jointi)};  % Update row labels (joints) in table
            app.arm_dh = [app.arm_dh;dhi];  % Update arm dh parameters
            
            % Read radio button joint type value, convert to a string,
            % append onto list of joint types
            app.jointTypes = [app.jointTypes;convertCharsToStrings(app.JointTypeButtonGroup.SelectedObject.Text)];
            
            % Extract parameters to put in table
            a = app.arm_dh(:,1);
            alpha = app.arm_dh(:,2);
            d = app.arm_dh(:,3);
            theta = app.arm_dh(:,4);
            Length = app.Links;
            JointType = app.jointTypes;
            
            app.jointi = app.jointi + 1;    % Increment joint


            % Update table
            app.armTable = table(JointType,Length,a,alpha,d,theta,'RowNames',app.jointsTable);
            app.CurrentArmTable.Data = app.armTable;
            app.CurrentArmTable.ColumnName = app.armTable.Properties.VariableNames;
            app.CurrentArmTable.RowName = app.armTable.Properties.RowNames;
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SimulationTab;   % Change to "Simulation" when you press finished on arm creation
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ArmDefinitionTab;    % Change to "Arm Definition" when you first start
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome!';
            app.STARTButton = uibutton(app.WelcomeTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Image = uiimage(app.WelcomeTab);
            app.Image.ImageSource = 'splash.png';
            app.ArmDefinitionTab = uitab(app.TabGroup);
            app.ArmDefinitionTab.Title = 'Arm Definition';
            app.AddJointstoArmLabel = uilabel(app.ArmDefinitionTab);
            app.AddJointstoArmLabel.Text = 'Add Joints to Arm';
            app.JointTypeButtonGroup = uibuttongroup(app.ArmDefinitionTab);
            app.JointTypeButtonGroup.Title = 'Joint Type';
            app.RevoluteButton = uiradiobutton(app.JointTypeButtonGroup);
            app.RevoluteButton.Text = 'Revolute';
            app.RevoluteButton.Value = true;
            app.PrismaticButton = uiradiobutton(app.JointTypeButtonGroup);
            app.PrismaticButton.Text = 'Prismatic';
            app.EnterDHParametersPanel = uipanel(app.ArmDefinitionTab);
            app.EnterDHParametersPanel.Title = 'Enter DH Parameters:';
            app.ORLabel = uilabel(app.EnterDHParametersPanel);
            app.ORLabel.Text = 'OR';
            app.FindDHParametersForMeButton = uibutton(app.EnterDHParametersPanel, 'push');
            app.FindDHParametersForMeButton.Text = 'Find DH Parameters For Me';
            app.aEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.dEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.thetaEditFieldLabel = uilabel(app.EnterDHParametersPanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.EnterDHParametersPanel, 'numeric');
            app.CurrentArmLabel = uilabel(app.ArmDefinitionTab);
            app.CurrentArmLabel.Text = 'Current Arm:';
            app.AddAboveJointtoArmButton = uibutton(app.ArmDefinitionTab, 'push');
            app.AddAboveJointtoArmButton.ButtonPushedFcn = createCallbackFcn(app, @AddAboveJointtoArmButtonPushed, true);
            app.AddAboveJointtoArmButton.Text = 'Add Above Joint to Arm';
            app.FinishedButton = uibutton(app.ArmDefinitionTab, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished!';
            app.ClearArmandStartOverButton = uibutton(app.ArmDefinitionTab, 'push');
            app.ClearArmandStartOverButton.Text = 'Clear Arm and Start Over';
            app.LinkLengthmEditFieldLabel = uilabel(app.ArmDefinitionTab);
            app.LinkLengthmEditFieldLabel.Text = 'Link Length (m)';
            app.LinkLengthmEditField = uieditfield(app.ArmDefinitionTab, 'numeric');
            app.CurrentArmTable = uitable(app.ArmDefinitionTab);
            app.CurrentArmTable.ColumnName = {'JointType'; 'Length'; 'a'; 'alpha'; 'd'; 'theta'};
            app.CurrentArmTable.RowName = {};
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboticsPackage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end